classdef excelreader_difference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NewFilePathTextArea           matlab.ui.control.TextArea
        NewFilePathTextAreaLabel      matlab.ui.control.Label
        OldFIlePathTextArea           matlab.ui.control.TextArea
        OldFIlePathTextAreaLabel      matlab.ui.control.Label
        GenerateDifferenceFIleButton  matlab.ui.control.Button
        LoadNewxlsxfileButton         matlab.ui.control.Button
        LoadOldxlsfileButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        file_old % Description
        file_new
        file_to_save
    end
    
    methods (Access = private)
              
        function [] = compare_excel_rows(app,file_1,file_2,file_to_write)
            %file_1='C:\Users\olaja\Downloads\Översikt_ESSF10_uppgifter_och_inlämningar 2023-03-24 11_22 (1).xlsx';    
            opts = detectImportOptions(file_1);
            for i=opts.VariableNames
                opts = setvartype(opts, i, 'char');
            end
            opts.PreserveVariableNames=true;
            T = readtable(file_1,opts);
            %file_2='C:\Users\olaja\Downloads\2023-04-18 14_13 (1).xlsx';
            opts = detectImportOptions(file_2);
            for i=opts.VariableNames
                opts = setvartype(opts, i, 'char');
            end
            opts.PreserveVariableNames=true;
            T2 = readtable(file_2,opts);
            t_new= [T; T2];
            [~, ia,ic]=unique(t_new,"rows");    
            dups=logical.empty();
            for i=1:numel(ic)
                if sum(ic==ic(i))>=2
                    dups(i)=true;
                else
                    dups(i)=false;
                end
            end
            t_new(dups,:)=[];
            t_new=sortrows(t_new);
            
            dups=logical.empty();
            [C, ia,ic]=unique(t_new.Personnummer);
            for i=1:numel(ic)
                if sum(ic==ic(i))==2
                    dups(i)=true;
                else
                    dups(i)=false;
                end
            end
            singlets=~dups;
            t_duplets=t_new(dups,:);
            t_singlets=t_new(singlets,:);
            
            %loop thruogh all variables excel first 5
            %[unique_personnr,ia,ic]=unique(t_new.Personnummer)
            for i = 1:2:height(t_duplets) 
                for j=6:width(t_duplets)
                    if strcmp(t_duplets{i,j},t_duplets{i+1,j})==1
                        t_duplets{i,j}={''};
                        t_duplets{i+1,j}={''};        
                    end
                end
            end
            t_to_write=[t_singlets ;t_duplets];
            writetable(t_to_write,file_to_write)
            f = msgbox('operation sucesful');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadOldxlsfileButton
        function LoadOldxlsfileButtonPushed(app, event)
            [file,path]=uigetfile('*.xlsx','Select an OLD xlsx file');
            if file~=0              
                app.file_old=fullfile(path,file);
                app.OldFIlePathTextArea.Value=app.file_old;
            else
                disp('operation cancelled')
            end
            
            
        end

        % Button pushed function: LoadNewxlsxfileButton
        function LoadNewxlsxfileButtonPushed(app, event)
            [file,path]=uigetfile('*.xlsx','Select a NEW xlsx file');
            if file~=0
                app.file_new=fullfile(path,file);
                app.NewFilePathTextArea.Value= app.file_new;
            else
                disp('operation cancelled')
            end
            
        end

        % Button pushed function: GenerateDifferenceFIleButton
        function GenerateDifferenceFIleButtonPushed(app, event)
           
            [file,path]=uiputfile('*.xlsx','Name the new file', 'difference_new_old.xlsx');         
            if file~=0
                app.file_to_save=fullfile(path,file);
                app.compare_excel_rows(app.file_old,app.file_new,app.file_to_save)
            else
                disp('operation cancelled')
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadOldxlsfileButton = uibutton(app.UIFigure, 'push');
            app.LoadOldxlsfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOldxlsfileButtonPushed, true);
            app.LoadOldxlsfileButton.Text = 'Load Old xls file';
            app.LoadNewxlsxfileButton = uibutton(app.UIFigure, 'push');
            app.LoadNewxlsxfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewxlsxfileButtonPushed, true);
            app.LoadNewxlsxfileButton.Text = 'Load New xlsx file';
            app.GenerateDifferenceFIleButton = uibutton(app.UIFigure, 'push');
            app.GenerateDifferenceFIleButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDifferenceFIleButtonPushed, true);
            app.GenerateDifferenceFIleButton.Text = 'Generate Difference FIle';
            app.OldFIlePathTextAreaLabel = uilabel(app.UIFigure);
            app.OldFIlePathTextAreaLabel.Text = 'Old FIle Path';
            app.OldFIlePathTextArea = uitextarea(app.UIFigure);
            app.NewFilePathTextAreaLabel = uilabel(app.UIFigure);
            app.NewFilePathTextAreaLabel.Text = 'New File Path';
            app.NewFilePathTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = excelreader_difference

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end